classdef analysis_TimeLag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        Switch                  matlab.ui.control.Switch
        MaximumLagSpinner       matlab.ui.control.Spinner
        MaximumLagSpinnerLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        Label                   matlab.ui.control.Label
        ProcessButton           matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            setappdata(app.UITable,'data',data)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            data = getappdata(app.UITable,'data');

            switch app.Switch.Value
                case 'Time Lag'
                    lag = app.MaximumLagSpinner.Value;
                case 'AMI'
            end


            % Delete the dialog box
            % delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Label = uilabel(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.MaximumLagSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumLagSpinnerLabel.Text = 'Maximum Lag';
            app.MaximumLagSpinner = uispinner(app.UIFigure);
            app.MaximumLagSpinner.Limits = [1 Inf];
            app.MaximumLagSpinner.RoundFractionalValues = 'on';
            app.MaximumLagSpinner.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Time Lag', 'AMI'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Time Lag';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_TimeLag(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end