classdef BAR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BiomechanicsAnalysisandReportingApplicationLabel  matlab.ui.control.Label
        Log                            matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        DatabaseSearchTab              matlab.ui.container.Tab
        CopyButton                     matlab.ui.control.Button
        MoveButton                     matlab.ui.control.Button
        ExcludeLabel                   matlab.ui.control.Label
        IncludeLabel                   matlab.ui.control.Label
        SearchTagsExclude              matlab.ui.control.EditField
        IdentifyGroupsButton           matlab.ui.control.Button
        Database                       matlab.ui.control.EditField
        SelectWorkingDatabaseButton    matlab.ui.control.Button
        SaveCurrentDataButton          matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        FindButton                     matlab.ui.control.Button
        TableFiles                     matlab.ui.control.Table
        SearchTagsInclude              matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        EquipmentDropDown              matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown          matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        Directory                      matlab.ui.control.EditField
        SelectDirectorytoSearchButton  matlab.ui.control.Button
        TreatmentTab                   matlab.ui.container.Tab
        ProcessButton                  matlab.ui.control.Button
        SelectTreatmentModuleDropDown  matlab.ui.control.DropDown
        SelectTreatmentModuleDropDownLabel  matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        RunAnalysisButton              matlab.ui.control.Button
        AnalysisDropDown               matlab.ui.control.DropDown
        AnalysisDropDownLabel          matlab.ui.control.Label
        ReviewTab                      matlab.ui.container.Tab
        ReviewDropDown                 matlab.ui.control.DropDown
        AnalysisDropDown_2Label        matlab.ui.control.Label
        ReviewAnalysisButton           matlab.ui.control.Button
        StatisticsTab                  matlab.ui.container.Tab
        ExportTab                      matlab.ui.container.Tab
        AnalysesAvailableforExportListBox  matlab.ui.control.ListBox
        AnalysesAvailableforExportListBoxLabel  matlab.ui.control.Label
        ExportResultsButton            matlab.ui.control.Button
        FormatsDropDown                matlab.ui.control.DropDown
        FormatsDropDownLabel           matlab.ui.control.Label
        UITableExport                  matlab.ui.control.Table
        ButtonFormatExport             matlab.ui.control.Button
        ConfigurationTab               matlab.ui.container.Tab
        UpdateConfigurationFileButton  matlab.ui.control.Button
        FilesLoad                      matlab.ui.control.EditField
        FolderlocationofloadingscriptsEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        AppAnalysis % Description
    end
    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)

            m_files = dir(m_directory);
            m_tags_include = app.SearchTagsInclude.Value;
            m_tags_exclude = app.SearchTagsExclude.Value;
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            end
            if ~isempty(m_tags_exclude)
                m_tags_exclude = textscan(m_tags_exclude,'%s','Delimiter',',');
            end

            for i = 3:length(m_files)
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        n = length(files.name);
                    end
                    [~, ~, ext] = fileparts(m_files(i).name);
                    bol_include = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_include{1},'uni',0);
                    bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_exclude{1},'uni',0);
                    if (strcmp(ext, ['.' app.FileExtensionDropDown.Value]) && ((strcmp(m_tags_include, '') || ~any(cellfun(@isempty,bol_include))) && (strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude)))))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes/1000;
                    end
                end
                if rem(length(m_files),10) == 0
                    app.TableFiles.Data = struct2table(files);
                    pause(0.001)
                end
            end
            
        end
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001 file search started'];
                case '002'
                    text{length(text) + 1,1} = ['002 file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' MB total'];
                case '003'
                    text{length(text) + 1,1} = ['003 error reading file: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['004 loading files started for: ' newText];
                case '005'
                    text{length(text) + 1,1} = ['005 loading files complete for: ' newText];
                case '006'
                    text{length(text) + 1,1} = ['006 results not found'];
                case '007'
                    text{length(text) + 1,1} = ['007 results exported'];
                case '008'
                    text{length(text) + 1,1} = ['008 results formated for export and displayed in table'];
                case '009'
                    text{length(text) + 1,1} = ['009 results formated for exported but are not compatible with the table'];
                case '010'
                    text{length(text) + 1,1} = ['010 created Results folder at ' newText];
                case '011'
                    text{length(text) + 1,1} = ['011 created Figure folder at ' newText];
                case '012'
                    text{length(text) + 1,1} = ['012 files saving....'];
                case '013'
                    text{length(text) + 1,1} = ['013 files saved'];
                case '014'
                    text{length(text) + 1,1} = ['014 moving files...'];
                case '015'
                    text{length(text) + 1,1} = ['015 moving files complete'];
                case '016'
                    text{length(text) + 1,1} = ['016 no files found in the selected directory'];
                case '017'
                    text{length(text) + 1,1} = ['017 error moving file: ' newText];
                case '018'
                    text{length(text) + 1,1} = ['018 copying files...'];
                case '019'
                    text{length(text) + 1,1} = ['019 copying files complete'];
                case '020'
                    text{length(text) + 1,1} = ['020 error copying file: ' newText];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
            
        end
        
        function dataExport = formatBUCaltest(app, data)
            
            files = fieldnames(data.raw);
            for i = 1:length(files)
                measures = fieldnames(data.raw.(files{i}));
                for j = 1:length(measures)
                    ind3 = strfind(measures{j}, '_');
                    if ind3(1) > 4
                        ind3 = [4, ind3];
                    end
                    if strcmp(measures{j}(ind3(1) + 1), 'M')
                        forcePlates{j,1} = measures{j}(1:3);
                    else
                        forcePlates{j,1} = measures{j}(1:ind3(1)-1);
                    end
                end
                forcePlates = unique(forcePlates);
                for j = 1:length(forcePlates)
                    if ~contains(forcePlates{j},'FP')
                        continue
                    end
                    ind = 1;
                    trials = {};
                    for k = 1:length(measures)
                        if contains(measures{k},forcePlates{j})
                            ind3 = strfind(measures{k}, '_');
                            if ind3(1) > 4
                                ind3 = [4, ind3];
                            end
                            if strcmp(measures{k}(ind3(1) + 1), 'M')
                                trials{ind, 1} = measures{k}(4:ind3(1)-1);
                            else
                                trials{ind,1} = measures{k}(ind3(1)+1:ind3(2)-1);
                            end
                            ind = ind + 1;
                        end
                    end
                    trials = unique(trials);
                    trialsN = str2double(trials);
                    for k = 1:length(trials)
                        ind4 = [];
                        switch trialsN(k)
                            case 1
                                ind4 = 0;
                            case 2
                                ind4 = 8;
                            case 3
                                ind4 = 16;
                            case 4
                                ind4 = 24;
                            case 5
                                ind4 = 32;
                        end

                        if i == 1
                            dataExport.(forcePlates{j}){1, ind4 + 1} = trialsN(k);
                            dataExport.(forcePlates{j}){1, 41} = 'Force Orientation Error';
                            dataExport.(forcePlates{j}){2, ind4 + 1} = 'Date';
                            dataExport.(forcePlates{j}){2, ind4 + 2} = 'xmean';
                            dataExport.(forcePlates{j}){2, ind4 + 3} = 'xsd';
                            dataExport.(forcePlates{j}){2, ind4 + 4} = 'ymean';
                            dataExport.(forcePlates{j}){2, ind4 + 5} = 'ysd';
                            dataExport.(forcePlates{j}){2, ind4 + 6} = 'zmean';
                            dataExport.(forcePlates{j}){2, ind4 + 7} = 'zsd';
                            dataExport.(forcePlates{j}){2, 41} = 'Date';
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1)} = [num2str(trialsN(k)) 'mean'];
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1) + 1} = [num2str(trialsN(k)) 'sd'];
                        end
                        dataExport.(forcePlates{j}){i + 2, ind4 + 1} = datestr(datetime(files{i}(4:end),'InputFormat','yyyyMMdd'));

                        if k == 1
                            dataExport.(forcePlates{j}){i + 2, 41} = datestr(datetime(files{i}(4:end),'InputFormat','yyyyMMdd'));
                        end

                        try
                            dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                        catch
                            try
                                dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                            catch
                                try
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                                catch
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                                end
                            end
                        end
                    end
                end
            end

        end
        
        function createDatabase(app)
            m_path = app.Database.Value;

            folders = dir(m_path);

            if ~any(contains({folders.name}, 'Results'))
                mkdir([m_path '\Results'])
                printLog(app, '010', [m_path '\Results'])
            end
            if ~any(contains({folders.name}, 'Figures'))
                mkdir([m_path '\Figures'])
                printLog(app, '011', [m_path '\Figures'])
            end
            if ~any(contains({folders.name}, 'Export'))
                mkdir([m_path '\Export'])
                printLog(app, '011', [m_path '\Export'])
            end

            config = loadConfig(app);
            config.database = m_path;
            saveConfig(app, config);
            
        end
        
        function identifyFileTypes(app)
            config = loadConfig(app);

            m_files = dir(app.FilesLoad.Value);
            config.options.filesload = app.FilesLoad.Value;

            config.options.filetypes = [];

            for i = 1:height(m_files)
                if contains(m_files(i).name, 'load') && ~contains(m_files(i).name, 'asv')
                    parts = textscan(m_files(i).name, '%s%s%s', 'Delimiter', '_');
                    config.options.filetypes.(parts{2}{1}) = parts{3}{1}(1:end-2);
                end

            end

            app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
            saveConfig(app, config)
            
            app.FileExtensionDropDownValueChanged;

        end
    end
    
    methods (Access = public)
        
        function analysisComplete(app, analysis)

            data = getappdata(app.BAR_App.TableFiles, 'data');
            
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            m_resultsPath = app.Database.Value;
            save([m_resultsPath '\Results\' analysis timeStamp], 'data', '-v7.3')

            if isfield(data, 'res')
                app.AnalysesAvailableforExportListBox.Items = fieldnames(data.res);
            end

        end
        
        function dataExport = formatResults(app, data)
            
            dataExport = struct;

            if ~any(strcmp(fieldnames(data),'res'))
                printLog(app, '006', {''})
                return
            end

            analysis = app.AnalysesAvailableforExportListBox.Value;
            if ~iscell(analysis)
                analysis = {analysis};
            end

            ind = 1;
            for i = 1:length(analysis)
                files = fieldnames(data.res.(analysis{i}));
                for j = 1:length(files)
                    obj = fieldnames(data.res.(analysis{i}).(files{j}));
                    for k = 1:length(obj)
                        sig = fieldnames(data.res.(analysis{i}).(files{j}).(obj{k}));
                        for ii = 1:length(sig)
                            results = fieldnames(data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}));
                            for jj = 1:length(data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).(results{1}))

                                if isempty(fieldnames(dataExport))
                                    ind3 = ind;
                                else
                                    ind2 = find(strcmp(dataExport.file, files{j}) & strcmp(dataExport.object, obj{k}) & strcmp(dataExport.signal, sig{ii}) & [dataExport.dimension{:}]' == jj);
                                    if isempty(ind2)
                                        ind3 = ind;
                                    else
                                        ind3 = ind2;
                                    end
                                end

                                dataExport.analysis{ind3, 1} = analysis{i};
                                dataExport.file{ind3, 1} = files{j};
                                dataExport.object{ind3, 1} = obj{k};
                                dataExport.signal{ind3, 1} = sig{ii};
                                dataExport.dimension{ind3, 1} = jj;

                                if isfield(data.raw.(files{j}).(obj{k}), 'groups')
                                    groups = fieldnames(data.raw.(files{j}).(obj{k}).groups);
                                    for kk = 1:length(groups)
                                        dataExport.(groups{kk}){ind3, 1} = data.raw.(files{j}).(obj{k}).groups.(groups{kk});
                                    end
                                end

                                switch analysis{i}
                                    case 'TimeLag'
                                        dataExport.firstminimum{ind3, 1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).tau1st{jj}(1, 1);
                                        dataExport.onefifthlag{ind3, 1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).tau15th{jj}(1, 1);
                                    case 'FNN'
                                        dataExport.embeddingdimension{ind3, 1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).dim{jj}(1, 1);
                                    case 'RQA'
                                        if isstruct(data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj})
                                            dataExport.dimensions{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.DIM;
                                            dataExport.embedding{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.EMB;
                                            dataExport.delay{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.DEL;
                                            dataExport.radius{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.RADIUS;
                                            dataExport.normalization{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.NORM;
                                            dataExport.zscore{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.ZSCORE;
                                            dataExport.length{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.Size;
                                            dataExport.rr{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.REC;
                                            dataExport.det{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.DET;
                                            dataExport.meanl{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.MeanL;
                                            dataExport.maxl{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.MaxL;
                                            dataExport.entropyl{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.EntrL;
                                            dataExport.lam{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.LAM;
                                            dataExport.meanv{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.MeanV;
                                            dataExport.maxv{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.MaxV;
                                            dataExport.entropyv{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.EntrV;
                                            dataExport.entropyw{ind3,1} = data.res.(analysis{i}).(files{j}).(obj{k}).(sig{ii}).results{jj}.EntrW;
                                        else
                                            dataExport.dimensions{ind3,1} = NaN;
                                            dataExport.embedding{ind3,1} = NaN;
                                            dataExport.delay{ind3,1} = NaN;
                                            dataExport.radius{ind3,1} = NaN;
                                            dataExport.normalization{ind3,1} = NaN;
                                            dataExport.zscore{ind3,1} = NaN;
                                            dataExport.length{ind3,1} = NaN;
                                            dataExport.rr{ind3,1} = NaN;
                                            dataExport.det{ind3,1} = NaN;
                                            dataExport.meanl{ind3,1} = NaN;
                                            dataExport.maxl{ind3,1} = NaN;
                                            dataExport.entropyl{ind3,1} = NaN;
                                            dataExport.lam{ind3,1} = NaN;
                                            dataExport.meanv{ind3,1} = NaN;
                                            dataExport.maxv{ind3,1} = NaN;
                                            dataExport.entropyv{ind3,1} = NaN;
                                            dataExport.entropyw{ind3,1} = NaN;
                                        end
                                end

                                ind = ind + 1;

                            end
                        end
                    end
                end
            end

        end

        function config = loadConfig(app)

            % Find the path to the main folder.
            m_analysisPath = mfilename('fullpath');
            ind = strfind(m_analysisPath,'\');
            m_analysisPath(ind(end):end) = [];

            % Create the config file if it doesn't exist.
            if ~isfile([m_analysisPath '\config.xml'])
                createConfig([m_analysisPath '\']);
            end

            % Load the config file.
            config = readstruct([m_analysisPath '\config.xml']);
            
        end
        
        
        function saveConfig(app, config)
            % Find the path to the main folder.
            m_analysisPath = mfilename('fullpath');
            ind = strfind(m_analysisPath,'\');
            m_analysisPath(ind(end):end) = [];
            
            % Load the config file.
            writestruct(config, [m_analysisPath '\config.xml']);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Find the path to the Analysis folder.
%             m_analysisPath = mfilename('fullpath');
%             ind = strfind(m_analysisPath,'\');
%             m_analysisPath(ind(end):end) = [];
%             m_analysisOptions = dir([m_analysisPath '\Analysis\']);
%             m_analysisOptions(1:2,:) = [];
%             % Set the list of analyses to the files found.
%             if iscell(m_analysisOptions)
%                 app.AnalysisDropDown.Items = m_analysisOptions.name;
%             else
%                 app.AnalysisDropDown.Items = {m_analysisOptions.name};
%             end

            % Load the config file.
            config = loadConfig(app);
            app.Directory.Value = config.directory;
            app.Database.Value = config.database;

            app.FilesLoad.Value = config.options.filesload;
            identifyFileTypes(app)
            
            app.AnalysisDropDown.Items = config.analysis(1:2:end);
            app.AnalysisDropDown.Value = app.AnalysisDropDown.Items{1};
            app.SelectTreatmentModuleDropDown.Items = config.treatment(1:2:end);
            app.ReviewDropDown.Items = config.review(1:2:end);
            app.FormatsDropDown.Items = config.export;
            
        end

        % Button pushed function: SelectDirectorytoSearchButton
        function SelectDirectorytoSearchButtonPushed(app, event)
            % Allow the user to select the parent directory.
            m_path = uigetdir(pwd,'Please select the data directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path == 0
                return
            end
            app.Directory.Value = m_path;

            config = loadConfig(app);
            config.directory = m_path;
            saveConfig(app, config)

        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            
            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTagsInclude.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            files = filesearch(app, files, m_directory);
            if isempty(fieldnames(files))
                printLog(app, '016')
                return
            end

            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);
%             app.TableFiles.ColumnName = fieldnames(files);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            printLog(app, '004', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            data = struct;
            ind1 = 1;

            for i = 1:height(files)

                name = files.name(i);
                ind2 = strfind(name, '.');
                name{1}(ind2{end}:end) = [];

                try
                    data.raw.(name{1}) = feval(filesLoad(ind3).name(1:end - 2), [files.folder{i} '\' files.name{i}]);
                catch
                    printLog(app, '003', [files.folder{i} '\' files.name{i}])
                end


            end
            
            setappdata(app.TableFiles,'data',data)

            if isfield(data, 'res')
                app.AnalysesAvailableforExportListBox.Items = fieldnames(data.res);
            end

            app.IdentifyGroupsButton.Enable = 1;

            printLog(app, '005', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');
            
            analysis = app.AnalysisDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.analysis, analysis));
            
            app.AppAnalysis = feval(config.analysis{ind + 1}, app, data, analysis);
            
        end

        % Value changed function: FileExtensionDropDown
        function FileExtensionDropDownValueChanged(app, event)
            % Get the currently selected file extension.
            value = app.FileExtensionDropDown.Value;
            % Load the configuration file with the matching file extensions
            % and equipment names.
            config = loadConfig(app);
            % Get the file extension options.
            options = fieldnames(config.options.filetypes);
            for i = 1:length(options)
                if strcmp(value, options{i})
                    app.EquipmentDropDown.Items = config.options.filetypes.(options{i});
                    break
                end
            end
            
        end

        % Button pushed function: ButtonFormatExport
        function ButtonFormatExportPushed(app, event)
            data = getappdata(app.TableFiles,'data');

            switch app.FormatsDropDown.Value
                case 'General'
                    dataExport = formatResults(app, data);
                    app.UITableExport.Data = struct2table(dataExport);
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')
                    printLog(app, '008')
                case 'BU Caltesting'
                    dataExport = formatBUCaltest(app, data);
                    setappdata(app.UITableExport,'dataExport',dataExport)
                    setappdata(app.UITableExport,'exportType','BU Caltesting')
                    printLog(app, '009')
            end
            
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            
            exportType = getappdata(app.UITableExport,'exportType');
            
            m_resultsPath = app.Database.Value;
            
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            switch exportType
                case 'General'
                    dataExport = app.UITableExport.Data;
                    writetable(dataExport,[m_resultsPath '\Export\' exportType timeStamp '.xlsx'])
                case 'BU Caltesting'
                    dataExport = getappdata(app.UITableExport,'dataExport');
                    fields = fieldnames(dataExport);
                    fileName = [m_resultsPath '\Export\' exportType timeStamp '.xlsx'];
                    for i = 1:length(fields)
                        xlswrite(fileName,dataExport.(fields{i}),fields{i})
                    end
            end

            printLog(app, '007')
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            data = getappdata(app.TableFiles, 'data');
            
            treatment = app.SelectTreatmentModuleDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.treatment, treatment));
            
            app.AppAnalysis = feval(config.treatment{ind + 1}, app, data);
        end

        % Button pushed function: SaveCurrentDataButton
        function SaveCurrentDataButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');

            m_resultsPath = app.Database.Value;

            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            fileName = [m_resultsPath '\' app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat'];
            printLog(app, '012')
            save(fileName, 'data', '-v7.3')
            printLog(app, '013')

        end

        % Button pushed function: SelectWorkingDatabaseButton
        function SelectWorkingDatabaseButtonPushed(app, event)
            % Allow the user to select the working directory to store data.
            m_path = uigetdir(pwd,'Please select the working directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path==0
                return
            end
            app.Database.Value = m_path;

            createDatabase(app);


        end

        % Button pushed function: ReviewAnalysisButton
        function ReviewAnalysisButtonPushed(app, event)
            data = getappdata(app.TableFiles, 'data');
            
            review = app.ReviewDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.review, review));
            
            app.AppAnalysis = feval(config.review{ind + 1}, app);
        end

        % Button pushed function: IdentifyGroupsButton
        function IdentifyGroupsButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');
            app.AppAnalysis = process_Groupings(app, data);

        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            
            printLog(app, '014')

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            for i = 1:height(files)

                [status, msg] = movefile([files.folder{i} '\' files.name{i}], targetDirectory);
                if status ~= 0
                    printLog(app, '017', msg)
                end

            end
            
            printLog(app, '015')
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            printLog(app, '018')

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            for i = 1:height(files)

                [status, msg] = copyfile([files.folder{i} '\' files.name{i}], targetDirectory);
                if status ~= 1
                    printLog(app, '020', msg)
                end

            end
            
            printLog(app, '019')
        end

        % Button pushed function: UpdateConfigurationFileButton
        function UpdateConfigurationFileButtonPushed(app, event)
            identifyFileTypes(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.SelectDirectorytoSearchButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectDirectorytoSearchButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectorytoSearchButtonPushed, true);
            app.SelectDirectorytoSearchButton.Text = 'Select Directory to Search';
            app.Directory = uieditfield(app.DatabaseSearchTab, 'text');
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {};
            app.FileExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FileExtensionDropDownValueChanged, true);
            app.FileExtensionDropDown.Value = {};
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {};
            app.EquipmentDropDown.Value = {};
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTagsInclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'File Name'; 'Folder'; 'Size (MB)'};
            app.TableFiles.RowName = {};
            app.FindButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.LoadButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveCurrentDataButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SaveCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataButtonPushed, true);
            app.SaveCurrentDataButton.Text = 'Save Current Data';
            app.SelectWorkingDatabaseButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectWorkingDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkingDatabaseButtonPushed, true);
            app.SelectWorkingDatabaseButton.Text = 'Select Working Database';
            app.Database = uieditfield(app.DatabaseSearchTab, 'text');
            app.IdentifyGroupsButton = uibutton(app.DatabaseSearchTab, 'push');
            app.IdentifyGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyGroupsButtonPushed, true);
            app.IdentifyGroupsButton.Enable = 'off';
            app.IdentifyGroupsButton.Text = 'Identify Groups';
            app.SearchTagsExclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.IncludeLabel = uilabel(app.DatabaseSearchTab);
            app.IncludeLabel.Text = 'Include';
            app.ExcludeLabel = uilabel(app.DatabaseSearchTab);
            app.ExcludeLabel.Text = 'Exclude';
            app.MoveButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.CopyButton = uibutton(app.DatabaseSearchTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.SelectTreatmentModuleDropDownLabel = uilabel(app.TreatmentTab);
            app.SelectTreatmentModuleDropDownLabel.Text = 'Select Treatment Module';
            app.SelectTreatmentModuleDropDown = uidropdown(app.TreatmentTab);
            app.ProcessButton = uibutton(app.TreatmentTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.ReviewAnalysisButton = uibutton(app.ReviewTab, 'push');
            app.ReviewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewAnalysisButtonPushed, true);
            app.ReviewAnalysisButton.Text = 'Review Analysis';
            app.AnalysisDropDown_2Label = uilabel(app.ReviewTab);
            app.AnalysisDropDown_2Label.Text = 'Analysis';
            app.ReviewDropDown = uidropdown(app.ReviewTab);
            app.ReviewDropDown.Items = {};
            app.ReviewDropDown.Value = {};
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ButtonFormatExport = uibutton(app.ExportTab, 'push');
            app.ButtonFormatExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonFormatExportPushed, true);
            app.ButtonFormatExport.Text = 'Format loaded results for export';
            app.UITableExport = uitable(app.ExportTab);
            app.UITableExport.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableExport.RowName = {};
            app.FormatsDropDownLabel = uilabel(app.ExportTab);
            app.FormatsDropDownLabel.Text = 'Formats';
            app.FormatsDropDown = uidropdown(app.ExportTab);
            app.FormatsDropDown.Items = {};
            app.FormatsDropDown.Value = {};
            app.ExportResultsButton = uibutton(app.ExportTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.AnalysesAvailableforExportListBoxLabel = uilabel(app.ExportTab);
            app.AnalysesAvailableforExportListBoxLabel.Text = 'Analyses Available for Export';
            app.AnalysesAvailableforExportListBox = uilistbox(app.ExportTab);
            app.AnalysesAvailableforExportListBox.Items = {};
            app.AnalysesAvailableforExportListBox.Multiselect = 'on';
            app.AnalysesAvailableforExportListBox.Value = {};
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.FolderlocationofloadingscriptsEditFieldLabel = uilabel(app.ConfigurationTab);
            app.FolderlocationofloadingscriptsEditFieldLabel.Text = 'Folder location of loading scripts';
            app.FilesLoad = uieditfield(app.ConfigurationTab, 'text');
            app.UpdateConfigurationFileButton = uibutton(app.ConfigurationTab, 'push');
            app.UpdateConfigurationFileButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConfigurationFileButtonPushed, true);
            app.UpdateConfigurationFileButton.Text = 'Update Configuration File';
            app.Log = uitextarea(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel = uilabel(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel.Text = 'Biomechanics Analysis and Reporting Application';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end