classdef process_Groupings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveResultsandExitButton     matlab.ui.control.Button
        FindDatesButton              matlab.ui.control.Button
        SelectsubjectgroupDropDown   matlab.ui.control.DropDown
        SelectsubjectgroupDropDownLabel  matlab.ui.control.Label
        SelectdatetagformatDropDown  matlab.ui.control.DropDown
        SelectdatetagformatDropDownLabel  matlab.ui.control.Label
        AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch  matlab.ui.control.Switch
        SwitchLabel                  matlab.ui.control.Label
        Checkfor_delimiterButton     matlab.ui.control.Button
        Log                          matlab.ui.control.TextArea
        UITable                      matlab.ui.control.Table
        Image                        matlab.ui.control.Image
        DataTreatmentLabel           matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['resample is not currently designed for non-integer resampling, rounding the sampling frequency to the nearest integer'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            
            setappdata(app.UITable, 'data', data)

            config = loadConfig(app.BAR_App);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.DataTreatmentLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Button pushed function: Checkfor_delimiterButton
        function findDelimiter(app, event)
            
            data = getappdata(app.UITable, 'data');

            n = [];
            files = fieldnames(data.raw);
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                groups = split(objs, '_');
                j = 1;
                while j <= size(groups, 2)
                    if numel(unique(groups(:,j))) == length(groups) || numel(unique(groups(:,j))) == 1
                        groups(:,j) = [];
                    else
                        n = [n;numel(unique(groups(:,j)))];
                        j = j + 1;
                    end
                end
                for j = 1:size(groups, 2)
                    groupsTable.(['g' num2str(j)]) = [unique(groups(:,j)); num2cell(nan(max(n) - n(j), 1))];
                end
                for j = 1:length(objs)
                    for k = 1:size(groups, 2)
                        data.raw.(files{i}).(objs{j}).groups.(['g' num2str(k)]) = groups{j,k};
                    end
                end
            end
            setappdata(app.UITable, 'data', data)
            app.UITable.Data = struct2table(groupsTable);
            app.UITable.ColumnName = fieldnames(groupsTable);

        end

        % Value changed function: 
        % AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch
        function findDates(app, event)
            value = app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch.Value;

            if strcmp(value, 'No')
                return
            end

            app.SelectdatetagformatDropDown.Enable = 1;

            app.SelectsubjectgroupDropDown.Enable = 1;
            groupTable = app.UITable.Data;
            app.SelectsubjectgroupDropDown.Items = groupTable.Properties.VariableNames;

            app.FindDatesButton.Enable = 1;
            
        end

        % Button pushed function: FindDatesButton
        function FindDatesButtonPushed(app, event)
            
            groupTable = app.UITable.Data;
            data = getappdata(app.UITable, 'data');

            dateFormat = app.SelectdatetagformatDropDown.Value;
            groupHeaders = groupTable.Properties.VariableNames;
            for i = 1:length(groupHeaders)
                potentialDate = groupTable.(groupHeaders{i}){1};
                try
                    isdatetime(datetime(potentialDate, 'InputFormat', dateFormat))
                    ind1 = i;
                end

            end

            newDatesArray = [];
            files = fieldnames(data.raw);
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                groups = split(objs, '_');
                for j = 1:length(groupTable.(app.SelectsubjectgroupDropDown.Value))
                    if ~isnan(groupTable.(app.SelectsubjectgroupDropDown.Value){j})
                        ind = find(contains(objs, groupTable.(app.SelectsubjectgroupDropDown.Value)(j)));
                        dates = datetime(groups(ind, ind1), 'InputFormat', dateFormat);
                        newdates = datenum(dates - min(dates))/7;
                        newDatesArray = [newDatesArray; newdates];
                        for k = 1:length(ind)
                            data.raw.(files{i}).(objs{ind(k)}).groups.(groupHeaders{ind1}) = newdates(k);
                        end
                    end
                end
            end

            h = height(groupTable);
            if h > numel(unique(newDatesArray))
                groupTable.(groupHeaders{ind1}) = [unique(newDatesArray); nan(h - numel(unique(newDatesArray)), 1)];
            end
            groupTable = groupTable(~any(ismissing(groupTable),2),:);
            app.UITable.Data = groupTable;

            setappdata(app.UITable, 'data', data)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DataTreatmentLabel = uilabel(app.UIFigure);
            app.DataTreatmentLabel.Text = 'Data Treatment';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.Checkfor_delimiterButton = uibutton(app.UIFigure, 'push');
            app.Checkfor_delimiterButton.ButtonPushedFcn = createCallbackFcn(app, @findDelimiter, true);
            app.Checkfor_delimiterButton.Text = 'Check for "_" delimiter';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Are there date tags in the names that represent specific visits?';
            app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch = uiswitch(app.UIFigure, 'slider');
            app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch.Items = {'No', 'Yes'};
            app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch.ValueChangedFcn = createCallbackFcn(app, @findDates, true);
            app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch.Value = 'No';
            app.SelectdatetagformatDropDownLabel = uilabel(app.UIFigure);
            app.SelectdatetagformatDropDownLabel.Enable = 'off';
            app.SelectdatetagformatDropDownLabel.Text = 'Select date tag format';
            app.SelectdatetagformatDropDown = uidropdown(app.UIFigure);
            app.SelectdatetagformatDropDown.Items = {'yyyyMMdd', 'ddMMyyyy'};
            app.SelectdatetagformatDropDown.Enable = 'off';
            app.SelectdatetagformatDropDown.Value = 'yyyyMMdd';
            app.SelectsubjectgroupDropDownLabel = uilabel(app.UIFigure);
            app.SelectsubjectgroupDropDownLabel.Enable = 'off';
            app.SelectsubjectgroupDropDownLabel.Text = 'Select subject group';
            app.SelectsubjectgroupDropDown = uidropdown(app.UIFigure);
            app.SelectsubjectgroupDropDown.Items = {};
            app.SelectsubjectgroupDropDown.Enable = 'off';
            app.SelectsubjectgroupDropDown.Value = {};
            app.FindDatesButton = uibutton(app.UIFigure, 'push');
            app.FindDatesButton.ButtonPushedFcn = createCallbackFcn(app, @FindDatesButtonPushed, true);
            app.FindDatesButton.Enable = 'off';
            app.FindDatesButton.Text = 'Find Dates';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Groupings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end