classdef process_Merge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RunMatchButton            matlab.ui.control.Button
        Delimiter                 matlab.ui.control.DropDown
        SelectthedelimitertosearchforinthefirstdatasetDropDownLabel  matlab.ui.control.Label
        Level2                    matlab.ui.control.DropDown
        SelectalevelfromtheseconddatasettomatchDropDownLabel  matlab.ui.control.Label
        Level1                    matlab.ui.control.DropDown
        SelectalevelfromthefirstdatasettomatchDropDownLabel  matlab.ui.control.Label
        SaveResultsandExitButton  matlab.ui.control.Button
        Log                       matlab.ui.control.TextArea
        UITable                   matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        MergeLabel                matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001, starting merge'];
                case '002'
                    text{length(text) + 1,1} = ['002, merge complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
        function updateTable(app)
            
            data1 = getappdata(app.UITable, 'data1');
            data2 = getappdata(app.UITable, 'data2');

            files1 = fieldnames(data1.raw);
            switch app.Level1.Value
                case 'File'
                    mergePotential.items1 = files1;
                case 'Object'
                    mergePotential.items1 = fieldnames(data1.raw.(files1{1}));
            end

            files2 = fieldnames(data2.raw);
            switch app.Level2.Value
                case 'File'
                    mergePotential.items2 = files2;
                case 'Object'
                    mergePotential.items2 = fieldnames(data2.raw.(files2{1}));
            end

            if length(mergePotential.items1) > length(mergePotential.items2)
                mergePotential.items2(end + 1:length(mergePotential.items1)) = {[]};
            elseif length(mergePotential.items1) < length(mergePotential.items2)
                mergePotential.items1(end + 1:length(mergePotential.items2)) = {[]};
            end

            app.UITable.Data = struct2table(mergePotential);
            app.UITable.ColumnName = {'Data Set 1', 'Data Set 2'};

        end
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            data1 = getappdata(app.BAR_App.TableFiles, 'data');
            data2 = app.BAR_App.loadData;
            
            setappdata(app.UITable, 'data1', data1)
            setappdata(app.UITable, 'data2', data2)

            updateTable(app)
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data1');
            analysis = app.MergeLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Button pushed function: RunMatchButton
        function RunMatchButtonPushed(app, event)

            printLog(app, '001', [])
            
            % This is the first data set that will be used as the base.
            data1 = getappdata(app.UITable, 'data1');
            % This is the second data set that will be inserted into the
            % base.
            data2 = getappdata(app.UITable, 'data2');

            % Pull the selected delimiter value from the dropdown.
            switch app.Delimiter.Value
                case 'Underscore'
                    delimiter = '_';
                case 'Period'
                    delimiter = '.';
                case 'Dash'
                    delimiter = '-';
            end
            
            switch app.Level1.Value
                case 'File'

                    tags = split(fieldnames(data1.raw), delimiter);
                    flag = 0;

                case 'Object'

                    flag = 1;
            end

            matches = struct;

            % Start iterating through the first data set at the files 
            % level.
            files1 = fieldnames(data1.raw);
            for i = 1:length(files1)
                matches.items1{i, 1} = files1{i};
                tags = split(files1{i}, delimiter);
                % Match obj2 to file1
                files2 = fieldnames(data2.raw);
                m = [];
                for j = 1:length(files2)
                    obj2 = fieldnames(data2.raw.(files2{j}));
                    for k = 1:length(obj2)
                        m(j, k) = 0;
                        for ii = 1:length(tags)
                            if any(strcmp(split(obj2{k}, delimiter), tags{ii}))
                                m(j, k) = m(j, k) + 1;
                            end
                        end
                    end
                end
                m_max = 0;
                I = [];
                m(m < length(tags)/2) = 0;
                [m_max, I] = max(m, [], 'all');
                if m_max ~= 0
                    [ind_f, ind_o] = find(m == m_max);
                    for j = 1:length(ind_f)
                        obj2 = fieldnames(data2.raw.(files2{ind_f(j)}));
                        matches.items2{i, 1} = obj2{ind_o(j)};
                        newName = regexprep(obj2{ind_o(j)}, tags, '');
                        while strcmp(newName(1), '_')
                            newName(1) = [];
                        end
                        data1.raw.(files1{i}).(newName) = data2.raw.(files2{ind_f(j)}).(obj2{ind_o(j)});
                        data1.raw.(files1{i}).(newName).file = files2{ind_f(j)};
                    end
                else
                    matches.items2{i, 1} = [];
                end
            end

            app.UITable.Data = struct2table(matches);

            setappdata(app.UITable, 'data1', data1);

            printLog(app, '002', [])

        end

        % Value changed function: Level1
        function Level1ValueChanged(app, event)
            value = app.Level1.Value;
            updateTable(app)
        end

        % Value changed function: Level2
        function Level2ValueChanged(app, event)
            value = app.Level2.Value;
            updateTable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.MergeLabel = uilabel(app.UIFigure);
            app.MergeLabel.Text = 'Merge';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.SelectalevelfromthefirstdatasettomatchDropDownLabel = uilabel(app.UIFigure);
            app.SelectalevelfromthefirstdatasettomatchDropDownLabel.Text = 'Select a level from the first data set to match.';
            app.Level1 = uidropdown(app.UIFigure);
            app.Level1.Items = {'File', 'Object'};
            app.Level1.ValueChangedFcn = createCallbackFcn(app, @Level1ValueChanged, true);
            app.Level1.Value = 'File';
            app.SelectalevelfromtheseconddatasettomatchDropDownLabel = uilabel(app.UIFigure);
            app.SelectalevelfromtheseconddatasettomatchDropDownLabel.Text = 'Select a level from the second data set to match.';
            app.Level2 = uidropdown(app.UIFigure);
            app.Level2.Items = {'File', 'Object'};
            app.Level2.ValueChangedFcn = createCallbackFcn(app, @Level2ValueChanged, true);
            app.Level2.Value = 'File';
            app.SelectthedelimitertosearchforinthefirstdatasetDropDownLabel = uilabel(app.UIFigure);
            app.SelectthedelimitertosearchforinthefirstdatasetDropDownLabel.Text = 'Select the delimiter to search for in the first data set.';
            app.Delimiter = uidropdown(app.UIFigure);
            app.Delimiter.Items = {'Underscore', 'Period', 'Dash'};
            app.Delimiter.Value = 'Underscore';
            app.RunMatchButton = uibutton(app.UIFigure, 'push');
            app.RunMatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunMatchButtonPushed, true);
            app.RunMatchButton.Text = 'Run Match';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Merge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end