classdef process_Segment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch                     matlab.ui.control.Switch
        Tree                       matlab.ui.container.Tree
        Node                       matlab.ui.container.TreeNode
        SegmentButton              matlab.ui.control.Button
        SelectMethodDropDown       matlab.ui.control.DropDown
        SelectMethodDropDownLabel  matlab.ui.control.Label
        AfterPanel                 matlab.ui.container.Panel
        Log                        matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        DataSegmentationLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = [newText{1}];
                case '002'
                    text{length(text) + 1,1} = 'analysis complete';
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);
            app.SelectMethodDropDown.Items = config.segmentation_general(1:2:end);
            
            setappdata(app.UIFigure, 'data', data)

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            
            app.Node.Text = type;
            files = fieldnames(data.raw);
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Node, 'Text', files{i});
                objs = fieldnames(data.(type).(files{i}));
                ind2 = 1;
                for j = 1:length(objs)
                    if strcmp(objs{j}, 'meta')
                        continue
                    end
                    uitreenode(app.Node.Children(ind1), 'Text', objs{j})
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    ind3 = 1;
                    for k = 1:length(sigs)
                        if strcmp(sigs{k}, 'freq')
                            continue
                        end
                        uitreenode(app.Node.Children(ind1).Children(ind2), 'Text', sigs{k});
                        ind3 = ind3 + 1;
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.DataSegmentationLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Before')
            app.DataSegmentationLabel = uilabel(app.UIFigure);
            app.DataSegmentationLabel.Text = 'Data Segmentation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uieditfield(app.UIFigure, 'text');
            app.Log.Editable = 'off';
            app.AfterPanel = uipanel(app.UIFigure);
            app.AfterPanel.Title = 'After';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {};
            app.SelectMethodDropDown.Value = {};
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.Text = 'Segment';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.Value = 'Raw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Segment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end